classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PaticleViewerUIFigure           matlab.ui.Figure
        SelectFileButton                matlab.ui.control.Button
        FilterPanel                     matlab.ui.container.Panel
        MinWeightSliderLabel            matlab.ui.control.Label
        MinWeightSlider                 matlab.ui.control.Slider
        HideparticlesnotupdatedrecentlyCheckBox  matlab.ui.control.CheckBox
        UpdateButton                    matlab.ui.control.StateButton
        ParticlenumberLabel             matlab.ui.control.Label
        ParticlenumberFilteredLabel     matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        UpdatetimeLabel                 matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        sLabel                          matlab.ui.control.Label
        ArrowsautoscaleCheckBox         matlab.ui.control.CheckBox
        HideresampledparticlesCheckBox  matlab.ui.control.CheckBox
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        OccupancyStatusPanel            matlab.ui.container.Panel
        ThresholdLabel                  matlab.ui.control.Label
        ThresholdEditField              matlab.ui.control.NumericEditField
        ShowOccupancyButton             matlab.ui.control.Button
        ColorbyzaxisCheckBox            matlab.ui.control.CheckBox
        RemoveparticlesCheckBox         matlab.ui.control.CheckBox
        VelocityCheckBox                matlab.ui.control.CheckBox
        MAP_LENGTH_VOXEL_NUMEditFieldLabel  matlab.ui.control.Label
        MAP_LENGTH_VOXEL_NUMEditField   matlab.ui.control.NumericEditField
        MAP_WIDTH_VOXEL_NUMEditFieldLabel  matlab.ui.control.Label
        MAP_WIDTH_VOXEL_NUMEditField    matlab.ui.control.NumericEditField
        MAP_HEIGHT_VOXEL_NUMEditFieldLabel  matlab.ui.control.Label
        MAP_HEIGHT_VOXEL_NUMEditField   matlab.ui.control.NumericEditField
        VOXEL_RESOLUTIONEditFieldLabel  matlab.ui.control.Label
        VOXEL_RESOLUTIONEditField       matlab.ui.control.NumericEditField
        ShowingoccupancystatusmaytakeawhilePleasewaitpatientlyLabel  matlab.ui.control.Label
    end

    
    methods (Access = private)
        
        function  drawMeshes(app)
            % Now draw meshes
            x_length = app.MAP_LENGTH_VOXEL_NUMEditField.Value;
            y_length = app.MAP_WIDTH_VOXEL_NUMEditField.Value;
            z_length = app.MAP_HEIGHT_VOXEL_NUMEditField.Value;
            voxel_size = app.VOXEL_RESOLUTIONEditField.Value;

            x_min = -x_length * voxel_size / 2.0;
            x_max = x_length * voxel_size / 2.0;
            y_min = -y_length * voxel_size / 2.0;
            y_max = y_length * voxel_size / 2.0;
            z_min = -z_length * voxel_size / 2.0;
            z_max = z_length * voxel_size / 2.0;

            x = floor(x_min):2:ceil(x_max); y = floor(y_min):2:ceil(y_max); z = floor(z_min):2:ceil(z_max);


            [X1,Y1,Z1] = meshgrid(x([1 end]),y,z);
            X1 = permute(X1,[2 1 3]); Y1 = permute(Y1,[2 1 3]); Z1 = permute(Z1,[2 1 3]);
            X1(end+1,:,:) = NaN; Y1(end+1,:,:) = NaN; Z1(end+1,:,:) = NaN;
            [X2,Y2,Z2] = meshgrid(x,y([1 end]),z);
            X2(end+1,:,:) = NaN; Y2(end+1,:,:) = NaN; Z2(end+1,:,:) = NaN;
            [X3,Y3,Z3] = meshgrid(x,y,z([1 end]));
            X3 = permute(X3,[3 1 2]); Y3 = permute(Y3,[3 1 2]); Z3 = permute(Z3,[3 1 2]);
            X3(end+1,:,:) = NaN; Y3(end+1,:,:) = NaN; Z3(end+1,:,:) = NaN;
            
            h = line([X1(:);X2(:);X3(:)], [Y1(:);Y2(:);Y3(:)], [Z1(:);Z2(:);Z3(:)]);
            set(h, 'Color',[0.8 0.8 1], 'LineWidth', 0.1, 'LineStyle','-');
            
            xlabel('x (m)');
            ylabel('y (m)');
            zlabel('z (m)');
        end
    end
    

    methods (Access = private)

        % Button pushed function: SelectFileButton
        function SelectFileButtonPushed(app, event)
            [file_name, file_path] = uigetfile('.csv', 'Choose a particle log file.');
            
            if isa(file_name, 'numeric')
                return;
            end
            
            app.UIAxes.cla;
            
            global particle_mat;
            particle_mat = csvread(strcat(file_path,file_name));
            
            global updated_time;
            file_name_cell = strsplit(file_name, '_');
            time_cell = strsplit(file_name_cell{5}, '.');
            
            updated_time = str2double(time_cell{1}) * 0.001;
            app.Label_4.Text = num2str(updated_time);
            
            global l;
            global w;
            [l, w] = size(particle_mat);
            
            app.Label.Text = num2str(l);
            
            UpdateButtonValueChanged(app, app.UpdateButton);
            
            app.UpdateButton.Enable = true;
            app.ShowOccupancyButton.Enable = true;
            
        end

        % Value changed function: UpdateButton
        function UpdateButtonValueChanged(app, event)
            value = app.UpdateButton.Value;
            app.UIAxes.cla;
            clf;
            
            global particle_mat;
            global l;
            global w;
            global updated_time;            
            
            % Filter particles by update time and weight
            hide_old_particles = app.HideparticlesnotupdatedrecentlyCheckBox.Value;
            hide_resampled_particles = app.HideresampledparticlesCheckBox.Value;

            
            particle_mat_filtered = zeros(l, w);
            valid_particle_counter = 0;
            for i=1:l
                if particle_mat(i, 8) > app.MinWeightSlider.Value && ...
                    (particle_mat(i, 9) > updated_time - 0.005 || ~hide_old_particles) && ...
                    (particle_mat(i, 1) > 0.8 || ~hide_resampled_particles)
                    valid_particle_counter = valid_particle_counter + 1;
                    for j=1:w
                        particle_mat_filtered(valid_particle_counter,j) = particle_mat(i,j);
                    end
                end
            end
            
            l1 = valid_particle_counter;
            
            app.Label_2.Text = num2str(l1);
            
            % set color
            if l1 > 0
                color_mat = zeros(l1, 3);
                for i= 1:l1
                    j = 1;
                    color_mat(i,j) = (particle_mat_filtered(i, 8)) * 20;
                    if color_mat(i,j) < 0
                        color_mat(i,j) = 0;
                    elseif color_mat(i,j) > 1
                        color_mat(i,j) = 1;
                    end
                    
%                     j = 2;
%                     color_mat(i,j) = 1-(particle_mat_filtered(i, 8)) * 20;
%                     if color_mat(i,j) < 0
%                         color_mat(i,j) = 0;
%                     elseif color_mat(i,j) > 1
%                         color_mat(i,j) = 1;
%                     end
                    
                end
                
                px = particle_mat_filtered(1:l1, 5);
                py = particle_mat_filtered(1:l1, 6);
                pz = particle_mat_filtered(1:l1, 7);
                weight = particle_mat_filtered(1:l1, 8);
                
                vx = particle_mat_filtered(1:l1, 2);
                vy = particle_mat_filtered(1:l1, 3);
                vz = particle_mat_filtered(1:l1, 4);
                
               
                scatter3(px, py, pz, weight*20, color_mat); % auto scale
                hold on;
                
                if app.ArrowsautoscaleCheckBox.Value == true
                    quiver3(px, py, pz, vx, vy, vz);
                else
                    quiver3(px, py, pz, vx, vy, vz, 0);
                end
                
            end
            

            drawMeshes(app);
           
            
            % draw histograms
            weight_hist = particle_mat_filtered(1:l1, 8);
            time_hist = particle_mat_filtered(1:l1, 9);
            histogram(app.UIAxes, weight_hist);
            histogram(app.UIAxes_2, time_hist);
                
            hold off;

        end

        % Value changing function: MinWeightSlider
        function MinWeightSliderValueChanging(app, event)
            changingValue = event.Value;
            app.Label_3.Text = num2str(changingValue);
        end

        % Button pushed function: ShowOccupancyButton
        function ShowOccupancyButtonPushed(app, event)
            
            if app.RemoveparticlesCheckBox.Value == true
                clf;
                scatter3(0, 0, 0, 0.2);
                drawMeshes(app);
            else
                UpdateButtonValueChanged(app, app.UpdateButton);
            end
                  
            hold on;

            global particle_mat;
            global l;
            
            x_length = app.MAP_LENGTH_VOXEL_NUMEditField.Value;
            y_length = app.MAP_WIDTH_VOXEL_NUMEditField.Value;
            z_length = app.MAP_HEIGHT_VOXEL_NUMEditField.Value;
            voxel_size = app.VOXEL_RESOLUTIONEditField.Value;

            x_min = -x_length * voxel_size / 2.0;
            x_max = x_length * voxel_size / 2.0;
            y_min = -y_length * voxel_size / 2.0;
            y_max = y_length * voxel_size / 2.0;
            z_min = -z_length * voxel_size / 2.0;
            z_max = z_length * voxel_size / 2.0;
            
            
            size = app.VOXEL_RESOLUTIONEditField.Value;
            x_num = x_length;
            y_num = y_length;
            z_num = z_length;
         
            occupancy_mat = zeros(x_num, y_num, z_num);
            occupancy_velocity_mat = zeros(x_num, y_num, z_num, 4);
            
            for i=1:l   %Calculate weights of each voxel
                x_index = floor((particle_mat(i, 5) - x_min) / size);
                y_index = floor((particle_mat(i, 6) - y_min) / size);
                z_index = floor((particle_mat(i, 7) - z_min) / size);
                    
                if size >= 0.2                    
                    x_index = min(x_index, x_length);
                    x_index = max(x_index, 1);
                    y_index = min(y_index, y_length);
                    y_index = max(y_index, 1);
                    z_index = min(z_index, z_length);
                    z_index = max(z_index, 1);
    
                    occupancy_mat(x_index, y_index, z_index) = occupancy_mat(x_index, y_index, z_index) + particle_mat(i,8);
                    
                    if app.VelocityCheckBox.Value == true
                        occupancy_velocity_mat(x_index, y_index, z_index, 1) = occupancy_velocity_mat(x_index, y_index, z_index, 1) + particle_mat(i, 2)*particle_mat(i,8);
                        occupancy_velocity_mat(x_index, y_index, z_index, 2) = occupancy_velocity_mat(x_index, y_index, z_index, 2) + particle_mat(i, 3)*particle_mat(i,8);
                        occupancy_velocity_mat(x_index, y_index, z_index, 3) = occupancy_velocity_mat(x_index, y_index, z_index, 3) + particle_mat(i, 4)*particle_mat(i,8);
                        occupancy_velocity_mat(x_index, y_index, z_index, 4) = occupancy_velocity_mat(x_index, y_index, z_index, 4) + particle_mat(i,8);
                    end
                else
                    neighbor_num = floor(0.2 / size / 2);
                    
                    for n=-neighbor_num : neighbor_num
                        for m=-neighbor_num : neighbor_num
                            for k=-neighbor_num : neighbor_num
                                current_x_index = min(x_index+n, x_length);
                                current_x_index = max(current_x_index, 1);
                                current_y_index = min(y_index+m, y_length);
                                current_y_index = max(current_y_index, 1);
                                current_z_index = min(z_index+k, z_length);
                                current_z_index = max(current_z_index, 1);
                                
                                occupancy_mat(current_x_index, current_y_index, current_z_index) = occupancy_mat(current_x_index, current_y_index, current_z_index) + particle_mat(i,8);
                    
                                if app.VelocityCheckBox.Value == true
                                    occupancy_velocity_mat(current_x_index, current_y_index, current_z_index, 1) = occupancy_velocity_mat(current_x_index, current_y_index, current_z_index, 1) + particle_mat(i, 2)*particle_mat(i,8);
                                    occupancy_velocity_mat(current_x_index, current_y_index, current_z_index, 2) = occupancy_velocity_mat(current_x_index, current_y_index, current_z_index, 2) + particle_mat(i, 3)*particle_mat(i,8);
                                    occupancy_velocity_mat(current_x_index, current_y_index, current_z_index, 3) = occupancy_velocity_mat(current_x_index, current_y_index, current_z_index, 3) + particle_mat(i, 4)*particle_mat(i,8);
                                    occupancy_velocity_mat(current_x_index, current_y_index, current_z_index, 4) = occupancy_velocity_mat(current_x_index, current_y_index, current_z_index, 4) + particle_mat(i,8);
                                end
                                
                            end
                         
                        end
                    end
                    
                    
                end


            end 
            
            max_color_value = z_num;
            jet_color = colormap(jet(max_color_value));
            autumn_color = colormap(autumn(max_color_value));

            
            threshold = app.ThresholdEditField.Value;
            
            voxel_center_p_v = zeros(x_num, y_num, z_num, 6);
            
            for i=1:x_num
                for j=1:y_num
                    for k=1:z_num
                        occupancy_probability = occupancy_mat(i,j,k);
                        if occupancy_probability > threshold
                            vert = [(i-1)*size + x_min, (j-1)*size + y_min, (k-1)*size + z_min; ...
                                    (i-1)*size + x_min, j*size + y_min, (k-1)*size + z_min; ...
                                    i*size + x_min, j*size + y_min, (k-1)*size + z_min; ...
                                    i*size + x_min, (j-1)*size + y_min, (k-1)*size + z_min; ...
                                    (i-1)*size + x_min, (j-1)*size + y_min, k*size + z_min; ...
                                    (i-1)*size + x_min, j*size + y_min, k*size + z_min; ...
                                    i*size + x_min, j*size + y_min, k*size + z_min; ...
                                    i*size + x_min, (j-1)*size + y_min, k*size + z_min ...                                    
                                   ];
                            fac = [1 2 3 4;2 6 7 3; 4 3 7 8; 1 5 8 4; 1 2 6 5; 5 6 7 8];
                            
                            %selected_color = jet_color(k,:);
                            %selected_color = autumn_color(max(21 - round((occupancy_probability - threshold)*12), 1),:);
                            %selected_color = jet_color(min(round(occupancy_probability*15), 21),:);
                            
                            
                            if app.ColorbyzaxisCheckBox.Value == true
                                selected_color = jet_color(k,:);
                                patch('Faces',fac,'Vertices',vert,'FaceColor',selected_color);  % patch function
                            else
                                selected_color = [min(round((occupancy_probability-threshold)*20), 21) / 21, 0, 0];
                                patch('Faces',fac,'Vertices',vert,'FaceColor',selected_color, 'LineStyle', 'none');  % patch function
                            end
                            
                            if app.VelocityCheckBox.Value == true
                                voxel_center_p_v(i,j,k,1) = (i-0.5)*size + x_min;
                                voxel_center_p_v(i,j,k,2) = (j-0.5)*size + y_min;
                                voxel_center_p_v(i,j,k,3) = (k-0.5)*size + z_min;
                                voxel_center_p_v(i,j,k,4) = occupancy_velocity_mat(i, j, k, 1) / occupancy_velocity_mat(i, j, k, 4);
                                voxel_center_p_v(i,j,k,5) = occupancy_velocity_mat(i, j, k, 2) / occupancy_velocity_mat(i, j, k, 4);
                                voxel_center_p_v(i,j,k,6) = occupancy_velocity_mat(i, j, k, 3) / occupancy_velocity_mat(i, j, k, 4);
                            end
                            
                            hold on;
                        end
                    end
                end
            end
            
            material shiny;
            %alpha('color');
            alphamap('rampdown');
            
            % draw velocity arrows
            if app.VelocityCheckBox.Value == true
                px = voxel_center_p_v(:,:,:,1);
                py = voxel_center_p_v(:,:,:,2);
                pz = voxel_center_p_v(:,:,:,3);
                vx = voxel_center_p_v(:,:,:,4);
                vy = voxel_center_p_v(:,:,:,5);
                vz = voxel_center_p_v(:,:,:,6);
                
                quiver3(px, py, pz, vx, vy, vz, 0, 'r', 'LineWidth', 1);

            end
                                
                     
            hold off;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PaticleViewerUIFigure = uifigure;
            app.PaticleViewerUIFigure.Name = 'Paticle Viewer';
            app.SelectFileButton = uibutton(app.PaticleViewerUIFigure, 'push');
            app.SelectFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFileButtonPushed, true);
            app.SelectFileButton.Text = 'Select File';
            app.FilterPanel = uipanel(app.PaticleViewerUIFigure);
            app.FilterPanel.Title = 'Filter';
            app.MinWeightSliderLabel = uilabel(app.FilterPanel);
            app.MinWeightSliderLabel.Text = 'Min Weight ';
            app.MinWeightSlider = uislider(app.FilterPanel);
            app.MinWeightSlider.Limits = [0 1];
            app.MinWeightSlider.ValueChangingFcn = createCallbackFcn(app, @MinWeightSliderValueChanging, true);
            app.HideparticlesnotupdatedrecentlyCheckBox = uicheckbox(app.FilterPanel);
            app.HideparticlesnotupdatedrecentlyCheckBox.Text = '   Hide particles not updated recently';
            app.UpdateButton = uibutton(app.FilterPanel, 'state');
            app.UpdateButton.ValueChangedFcn = createCallbackFcn(app, @UpdateButtonValueChanged, true);
            app.UpdateButton.Enable = 'off';
            app.UpdateButton.Text = 'Update';
            app.ParticlenumberLabel = uilabel(app.FilterPanel);
            app.ParticlenumberLabel.Text = 'Particle number';
            app.ParticlenumberFilteredLabel = uilabel(app.FilterPanel);
            app.ParticlenumberFilteredLabel.Text = {'Particle number Filtered'; ''};
            app.Label = uilabel(app.FilterPanel);
            app.Label.Text = '0';
            app.Label_2 = uilabel(app.FilterPanel);
            app.Label_2.Text = '0';
            app.Label_3 = uilabel(app.FilterPanel);
            app.Label_3.Text = '0';
            app.UpdatetimeLabel = uilabel(app.FilterPanel);
            app.UpdatetimeLabel.Text = 'Update time';
            app.Label_4 = uilabel(app.FilterPanel);
            app.Label_4.Text = '0';
            app.sLabel = uilabel(app.FilterPanel);
            app.sLabel.Text = 's';
            app.ArrowsautoscaleCheckBox = uicheckbox(app.FilterPanel);
            app.ArrowsautoscaleCheckBox.Text = '   Arrows autoscale';
            app.ArrowsautoscaleCheckBox.Value = true;
            app.HideresampledparticlesCheckBox = uicheckbox(app.FilterPanel);
            app.HideresampledparticlesCheckBox.Text = '   Hide resampled particles';
            app.UIAxes = uiaxes(app.PaticleViewerUIFigure);
            title(app.UIAxes, 'Weight Histogram')
            xlabel(app.UIAxes, 'Weight')
            ylabel(app.UIAxes, 'Paticle number')
            app.UIAxes_2 = uiaxes(app.PaticleViewerUIFigure);
            title(app.UIAxes_2, 'Update Time Histogram')
            xlabel(app.UIAxes_2, 'Time')
            ylabel(app.UIAxes_2, 'Particle number')
            app.OccupancyStatusPanel = uipanel(app.PaticleViewerUIFigure);
            app.OccupancyStatusPanel.Title = 'Occupancy Status';
            app.ThresholdLabel = uilabel(app.OccupancyStatusPanel);
            app.ThresholdLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.OccupancyStatusPanel, 'numeric');
            app.ThresholdEditField.Value = 0.7;
            app.ShowOccupancyButton = uibutton(app.OccupancyStatusPanel, 'push');
            app.ShowOccupancyButton.ButtonPushedFcn = createCallbackFcn(app, @ShowOccupancyButtonPushed, true);
            app.ShowOccupancyButton.Enable = 'off';
            app.ShowOccupancyButton.Text = 'Show Occupancy';
            app.ColorbyzaxisCheckBox = uicheckbox(app.OccupancyStatusPanel);
            app.ColorbyzaxisCheckBox.Text = 'Color by z axis';
            app.ColorbyzaxisCheckBox.Value = true;
            app.RemoveparticlesCheckBox = uicheckbox(app.OccupancyStatusPanel);
            app.RemoveparticlesCheckBox.Text = 'Remove particles';
            app.VelocityCheckBox = uicheckbox(app.OccupancyStatusPanel);
            app.VelocityCheckBox.Text = 'Velocity';
            app.MAP_LENGTH_VOXEL_NUMEditFieldLabel = uilabel(app.PaticleViewerUIFigure);
            app.MAP_LENGTH_VOXEL_NUMEditFieldLabel.Text = 'MAP_LENGTH_VOXEL_NUM';
            app.MAP_LENGTH_VOXEL_NUMEditField = uieditfield(app.PaticleViewerUIFigure, 'numeric');
            app.MAP_LENGTH_VOXEL_NUMEditField.RoundFractionalValues = 'on';
            app.MAP_LENGTH_VOXEL_NUMEditField.Value = 33;
            app.MAP_WIDTH_VOXEL_NUMEditFieldLabel = uilabel(app.PaticleViewerUIFigure);
            app.MAP_WIDTH_VOXEL_NUMEditFieldLabel.Text = 'MAP_WIDTH_VOXEL_NUM';
            app.MAP_WIDTH_VOXEL_NUMEditField = uieditfield(app.PaticleViewerUIFigure, 'numeric');
            app.MAP_WIDTH_VOXEL_NUMEditField.RoundFractionalValues = 'on';
            app.MAP_WIDTH_VOXEL_NUMEditField.Value = 33;
            app.MAP_HEIGHT_VOXEL_NUMEditFieldLabel = uilabel(app.PaticleViewerUIFigure);
            app.MAP_HEIGHT_VOXEL_NUMEditFieldLabel.Text = 'MAP_HEIGHT_VOXEL_NUM';
            app.MAP_HEIGHT_VOXEL_NUMEditField = uieditfield(app.PaticleViewerUIFigure, 'numeric');
            app.MAP_HEIGHT_VOXEL_NUMEditField.RoundFractionalValues = 'on';
            app.MAP_HEIGHT_VOXEL_NUMEditField.Value = 21;
            app.VOXEL_RESOLUTIONEditFieldLabel = uilabel(app.PaticleViewerUIFigure);
            app.VOXEL_RESOLUTIONEditFieldLabel.Text = 'VOXEL_RESOLUTION';
            app.VOXEL_RESOLUTIONEditField = uieditfield(app.PaticleViewerUIFigure, 'numeric');
            app.VOXEL_RESOLUTIONEditField.Value = 0.3;
            app.ShowingoccupancystatusmaytakeawhilePleasewaitpatientlyLabel = uilabel(app.PaticleViewerUIFigure);
            app.ShowingoccupancystatusmaytakeawhilePleasewaitpatientlyLabel.Text = 'Showing occupancy status may take a while. Please wait patiently.';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PaticleViewerUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PaticleViewerUIFigure)
        end
    end
end